classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CommodityLabel             matlab.ui.control.Label
        PriceEditFieldLabel        matlab.ui.control.Label
        PriceEditField             matlab.ui.control.NumericEditField
        TimeofneedDatePickerLabel  matlab.ui.control.Label
        TimeofneedDatePicker       matlab.ui.control.DatePicker
        FavorSpinnerLabel          matlab.ui.control.Label
        FavorSpinner               matlab.ui.control.Spinner
        PopularitySpinnerLabel     matlab.ui.control.Label
        PopularitySpinner          matlab.ui.control.Spinner
        AmountSpinnerLabel         matlab.ui.control.Label
        AmountSpinner              matlab.ui.control.Spinner
        NameEditFieldLabel         matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        AddtocartButton            matlab.ui.control.Button
        PopularitySliderLabel      matlab.ui.control.Label
        PopularitySlider           matlab.ui.control.Slider
        FavorSliderLabel           matlab.ui.control.Label
        FavorSlider                matlab.ui.control.Slider
        UrgencySliderLabel         matlab.ui.control.Label
        UrgencySlider              matlab.ui.control.Slider
        SetButton                  matlab.ui.control.Button
        BoxNameEditFieldLabel      matlab.ui.control.Label
        BoxNameEditField           matlab.ui.control.EditField
        BudgetEditFieldLabel       matlab.ui.control.Label
        BudgetEditField            matlab.ui.control.NumericEditField
        UITable2                   matlab.ui.control.Table
        DeleteButton_2             matlab.ui.control.Button
        SelectallButton_2          matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        DeleteButton               matlab.ui.control.Button
        SelectallButton            matlab.ui.control.Button
        WhattobuyLabel             matlab.ui.control.Label
        BudgetLabel                matlab.ui.control.Label
        GlobalPreferenceRateLabel  matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        SelectnoneButton           matlab.ui.control.Button
        SelectnoneButton_2         matlab.ui.control.Button
        WithlimitedbudgetfindingtheLabel  matlab.ui.control.Label
        SelectitemsintheCommodityTableleft2InputLabel  matlab.ui.control.Label
        ResultLabel                matlab.ui.control.Label
    end

    properties (Access = private)
        item = cell2table(cell(0, 7), "VariableNames", {'checked', 'name', 'price', ...
            'amount', 'emergency', 'preference', 'popularity'});
        rule = cell2table(cell(0, 4), "VariableNames", {'checked', 'item_label', ...
            'group_name', 'uplimitation'});
        ratio = zeros(1, 3);
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DeleteButton
        function Button_4Pushed(app, event)
            checked = cell2mat(app.item.checked);
            app.item(checked, :) = [];
            app.UITable.Data = app.item;
        end

        % Button pushed function: AddtocartButton
        function AddtocartButtonPushed(app, event)
            duration = days(app.TimeofneedDatePicker.Value - datetime('today'));
            new_item = {
                {false},               ... % selected
                app.NameEditField.Value, ... % name of commodity
                app.PriceEditField.Value,   ... % price
                app.AmountSpinner.Value,   ... % amount
                max(duration, 0),      ... % urgency
                app.FavorSpinner.Value,     ... % favor
                app.PopularitySpinner.Value    ... % popularity
            };
            app.item = [app.item; new_item];
            app.UITable.Data = app.item;
            app.UITable.ColumnEditable = true;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if event.Indices(2) == 1 || event.Indices(2) == 2
                app.item{event.Indices(1), event.Indices(2)} = {event.NewData};
            else
                app.item{event.Indices(1), event.Indices(2)} = event.NewData;
            end
        end

        % Button pushed function: SelectallButton
        function Button_6Pushed(app, event)
            app.item{:, 1} = {true};
            app.UITable.Data = app.item;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            new_rule = {
                {false},                                          ... % selected
                cell2mat(app.item.checked)' .* app.item.price',  ... % commodity selected
                app.BoxNameEditField.Value,                            ... % name
                app.BudgetEditField.Value                             ... % budget limitation
            };
            try
                app.rule = [app.rule; new_rule];
            catch
                app.TextArea.Value = "The commodities are changed. To renew budgets, " + ...
                    "pleace clear the old budgets first.";
            end
            app.UITable2.Data = app.rule;
            app.UITable2.ColumnEditable = true;
        end

        % Button pushed function: DeleteButton_2
        function DeleteButton_2Pushed(app, event)
            checked = cell2mat(app.rule.checked);
            app.rule(checked, :) = [];
            app.UITable2.Data = app.rule;
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            if event.Indices(2) == 1 || event.Indices(2) == 3
                app.rule{event.Indices(1), event.Indices(2)} = {event.NewData};
            else
                app.rule{event.Indices(1), event.Indices(2)} = event.NewData;
            end
        end

        % Button pushed function: SelectallButton_2
        function SelectallButton_2Pushed(app, event)
            app.rule{:, 1} = {true};
            app.UITable2.Data = app.rule;
        end

        % Value changed function: FavorSlider
        function FavorSliderValueChanged(app, event)
            app.ratio(2) = app.FavorSlider.Value;
            n_goods = size(app.item, 1);
            new_result = strings(n_goods, 1);
            utility = [app.item.popularity, app.item.preference, app.item.emergency]';
            try
                rcmd = BranchBound(utility, app.ratio, app.rule.item_label, ...
                    app.rule.uplimitation, app.item.amount);
                for i=1:n_goods
                    new_result(i) = sprintf("Commodity '%s' is suggected to buy %d amount.", app.item.name{i}, rcmd(i));
                end
            catch
                new_result = "The problem has no solution. Pleace make higher budgets and check if errors.";
            end
            app.TextArea.Value = new_result;
        end

        % Value changed function: PopularitySlider
        function PopularitySliderValueChanged(app, event)
            app.ratio(1) = app.PopularitySlider.Value;
            n_goods = size(app.item, 1);
            new_result = strings(n_goods, 1);
            utility = [app.item.popularity, app.item.preference, app.item.emergency]';
            try
                rcmd = BranchBound(utility, app.ratio, app.rule.item_label, ...
                    app.rule.uplimitation, app.item.amount);
                for i=1:n_goods
                    new_result(i) = sprintf("Commodity '%s' is suggected to buy %d amount.", app.item.name{i}, rcmd(i));
                end
            catch
                new_result = "The problem has no solution. Pleace make higher budgets and check if errors.";
            end
            app.TextArea.Value = new_result;
        end

        % Value changed function: UrgencySlider
        function UrgencySliderValueChanged(app, event)
            app.ratio(3) = app.UrgencySlider.Value;
            n_goods = size(app.item, 1);
            new_result = strings(n_goods, 1);
            utility = [app.item.popularity, app.item.preference, app.item.emergency]';
            try
                rcmd = BranchBound(utility, app.ratio, app.rule.item_label, ...
                    app.rule.uplimitation, app.item.amount);
                for i=1:n_goods
                    new_result(i) = sprintf("Commodity '%s' is suggected to buy %d amount.", app.item.name{i}, rcmd(i));
                end
            catch
                new_result = "The problem has no solution. Pleace make higher budgets and check if errors.";
            end
            app.TextArea.Value = new_result;
        end

        % Button pushed function: SelectnoneButton
        function SelectnoneButtonPushed(app, event)
            app.item{:,1} = {false};
            app.UITable.Data = app.item;
        end

        % Button pushed function: SelectnoneButton_2
        function SelectnoneButton_2Pushed(app, event)
            app.rule{:,1} = {false};
            app.UITable2.Data = app.rule;
        end

        % Callback function
        function ImageClicked(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CommodityLabel = uilabel(app.UIFigure);
            app.CommodityLabel.Text = 'Commodity';
            app.PriceEditFieldLabel = uilabel(app.UIFigure);
            app.PriceEditFieldLabel.Text = 'Price';
            app.PriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PriceEditField.Limits = [0.01 Inf];
            app.PriceEditField.ValueDisplayFormat = '%0.2f';
            app.PriceEditField.Value = 1;
            app.TimeofneedDatePickerLabel = uilabel(app.UIFigure);
            app.TimeofneedDatePickerLabel.Text = 'Time of need';
            app.TimeofneedDatePicker = uidatepicker(app.UIFigure);
            app.TimeofneedDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.FavorSpinnerLabel = uilabel(app.UIFigure);
            app.FavorSpinnerLabel.Text = 'Favor';
            app.FavorSpinner = uispinner(app.UIFigure);
            app.FavorSpinner.Limits = [1 10];
            app.FavorSpinner.Value = 1;
            app.PopularitySpinnerLabel = uilabel(app.UIFigure);
            app.PopularitySpinnerLabel.Text = 'Popularity';
            app.PopularitySpinner = uispinner(app.UIFigure);
            app.PopularitySpinner.Limits = [1 10];
            app.PopularitySpinner.Value = 1;
            app.AmountSpinnerLabel = uilabel(app.UIFigure);
            app.AmountSpinnerLabel.Text = 'Amount';
            app.AmountSpinner = uispinner(app.UIFigure);
            app.AmountSpinner.Limits = [1 Inf];
            app.AmountSpinner.Value = 1;
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.AddtocartButton = uibutton(app.UIFigure, 'push');
            app.AddtocartButton.ButtonPushedFcn = createCallbackFcn(app, @AddtocartButtonPushed, true);
            app.AddtocartButton.Text = 'Add to cart';
            app.PopularitySliderLabel = uilabel(app.UIFigure);
            app.PopularitySliderLabel.Text = 'Popularity';
            app.PopularitySlider = uislider(app.UIFigure);
            app.PopularitySlider.Limits = [0 1];
            app.PopularitySlider.ValueChangedFcn = createCallbackFcn(app, @PopularitySliderValueChanged, true);
            app.FavorSliderLabel = uilabel(app.UIFigure);
            app.FavorSliderLabel.Text = 'Favor';
            app.FavorSlider = uislider(app.UIFigure);
            app.FavorSlider.Limits = [0 1];
            app.FavorSlider.ValueChangedFcn = createCallbackFcn(app, @FavorSliderValueChanged, true);
            app.UrgencySliderLabel = uilabel(app.UIFigure);
            app.UrgencySliderLabel.Text = 'Urgency';
            app.UrgencySlider = uislider(app.UIFigure);
            app.UrgencySlider.Limits = [0 1];
            app.UrgencySlider.ValueChangedFcn = createCallbackFcn(app, @UrgencySliderValueChanged, true);
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.BoxNameEditFieldLabel = uilabel(app.UIFigure);
            app.BoxNameEditFieldLabel.Text = 'Box Name';
            app.BoxNameEditField = uieditfield(app.UIFigure, 'text');
            app.BudgetEditFieldLabel = uilabel(app.UIFigure);
            app.BudgetEditFieldLabel.Text = 'Budget';
            app.BudgetEditField = uieditfield(app.UIFigure, 'numeric');
            app.BudgetEditField.Limits = [0.01 Inf];
            app.BudgetEditField.ValueDisplayFormat = '%.2f';
            app.BudgetEditField.Value = 1;
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {''; 'Cond. Matrix'; 'Box Name'; 'Budget'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.DeleteButton_2 = uibutton(app.UIFigure, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_2Pushed, true);
            app.DeleteButton_2.Text = 'Delete';
            app.SelectallButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectallButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectallButton_2Pushed, true);
            app.SelectallButton_2.Text = 'Select all';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'Name'; 'Price'; 'Amount'; 'Due'; 'Favor'; 'Pop.'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SelectallButton = uibutton(app.UIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.SelectallButton.Text = 'Select all';
            app.WhattobuyLabel = uilabel(app.UIFigure);
            app.WhattobuyLabel.Text = 'What to buy';
            app.BudgetLabel = uilabel(app.UIFigure);
            app.BudgetLabel.Text = 'Budget';
            app.GlobalPreferenceRateLabel = uilabel(app.UIFigure);
            app.GlobalPreferenceRateLabel.Text = 'Global Preference (Rate)';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Slide the global preference to view the result.'};
            app.SelectnoneButton = uibutton(app.UIFigure, 'push');
            app.SelectnoneButton.ButtonPushedFcn = createCallbackFcn(app, @SelectnoneButtonPushed, true);
            app.SelectnoneButton.Text = 'Select none';
            app.SelectnoneButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectnoneButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectnoneButton_2Pushed, true);
            app.SelectnoneButton_2.Text = 'Select none';
            app.WithlimitedbudgetfindingtheLabel = uilabel(app.UIFigure);
            app.WithlimitedbudgetfindingtheLabel.Text = 'With limited budget, finding the combination of commodity leading to the maximum utility.';
            app.SelectitemsintheCommodityTableleft2InputLabel = uilabel(app.UIFigure);
            app.SelectitemsintheCommodityTableleft2InputLabel.Text = {'1. Select items in the Commodity Table left.'; '2. Input:'};
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end